classdef SortingVisualizedProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SortingVisualized              matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        VisualizeSortTab               matlab.ui.container.Tab
        MinimumValueSpinnerLabel       matlab.ui.control.Label
        MinimumValueSpinner            matlab.ui.control.Spinner
        MaximumValueSpinnerLabel       matlab.ui.control.Label
        MaximumValueSpinner            matlab.ui.control.Spinner
        ArrayLengthSpinnerLabel        matlab.ui.control.Label
        ArrayLengthSpinner             matlab.ui.control.Spinner
        RandomArrayLabel               matlab.ui.control.Label
        SortedArrayLabel               matlab.ui.control.Label
        LabelUnsorted                  matlab.ui.control.Label
        LabelSorted                    matlab.ui.control.Label
        SortingAlgorithmsButtonGroup   matlab.ui.container.ButtonGroup
        InsertionButton                matlab.ui.control.RadioButton
        MergeButton                    matlab.ui.control.RadioButton
        BubbleButton                   matlab.ui.control.RadioButton
        BucketButton                   matlab.ui.control.RadioButton
        RadixButton                    matlab.ui.control.RadioButton
        QuickButton                    matlab.ui.control.RadioButton
        HeapButton                     matlab.ui.control.RadioButton
        CountingButton                 matlab.ui.control.RadioButton
        CreateArrayButton              matlab.ui.control.Button
        SpeedKnobLabel                 matlab.ui.control.Label
        SpeedKnob                      matlab.ui.control.DiscreteKnob
        UIAxes                         matlab.ui.control.UIAxes
        AboutTab                       matlab.ui.container.Tab
        AuthorMuhammetFurkanMUTULabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        EMailmfurkanmustugmailomLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateArrayButton
        function CreateArrayButtonPushed(app, event)
            MinVal = app.MinimumValueSpinner.Value;
            MaxVal = app.MaximumValueSpinner.Value;
            ArrayLength = app.ArrayLengthSpinner.Value;
            
            RandomArray = randi([MinVal,MaxVal],1,ArrayLength);
            UsedArray = RandomArray;
            app.LabelSorted.Text = num2str(RandomArray);
            app.LabelUnsorted.Text = num2str(UsedArray);
        end

        % Selection changed function: SortingAlgorithmsButtonGroup
        function SortingAlgorithmsButtonGroupSelectionChanged(app, event)
            selectedButton = app.SortingAlgorithmsButtonGroup.SelectedObject;
            import time.*
            switch(selectedButton.Text)
                case 'Insertion'
                    InsertionSortSelected;
                case 'Merge'
                    MergeSortSelected;
                case 'Bubble'
                    BubbleSortSelected;
                case 'Bucket'
                    BucketSortSelected;
                case 'Radix'
                    RadixSortSelected;
                case 'Quick'
                    QuickSortSelected;
                case 'Heap'
                    HeapSortSelected;
                case 'Counting'
                    CountingSortSelected;
            end
            
            function InsertionSortSelected
                ArrayLength = int64(app.ArrayLengthSpinner.Value);
                arr = str2num(app.LabelUnsorted.Text);
                speed=2;
                bar(app.UIAxes,arr,'b')
                text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                pause(0.5)
                for i=1:ArrayLength % we use for because  we look at the difference between numbers
                    %speed
                    value=app.SpeedKnob.Value;
                    if value=="Stop"
                        value=app.SpeedKnob.Value;
                        while true
                            pause(0.1)
                            if app.SpeedKnob.Value~="Stop"
                                break
                            end
                        end
                    end
                    if strcmp(value,'Clear')
                        cla(app.UIAxes)
                        break
                    elseif strcmp(value,'Slow')
                        speed=2;
                    elseif strcmp(value,'Normal')
                        speed=1;
                    elseif strcmp(value,'Fast')
                        speed=0.1;
                    end
                    for j=1:(ArrayLength-i)
                        if arr(j+1)<arr(j)
                            value=app.SpeedKnob.Value;
                            if value=="Stop"
                                value=app.SpeedKnob.Value;
                                while true
                                    pause(0.1)
                                    if app.SpeedKnob.Value~="Stop"
                                        break
                                    end
                                end
                            end
                            if strcmp(value,'Clear')
                                cla(app.UIAxes)
                                break
                            elseif strcmp(value,'Slow')
                                speed=2;
                            elseif strcmp(value,'Normal')
                                speed=1;
                            elseif strcmp(value,'Fast')
                                speed=0.1;
                            end
                            
                            bar(app.UIAxes,arr,'b')
                            text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                            hold(app.UIAxes,'on')
                            bar(app.UIAxes,j,arr(j),'g')
                            bar(app.UIAxes,j+1,arr(j+1),'g')
                            text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                            hold(app.UIAxes,'off')
                            
                            temporary = arr(j);
                            arr(j) = arr(j+1);
                            arr(j+1) = temporary;
                            pause(speed)
                            bar(app.UIAxes,arr,'b')
                            text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                            hold(app.UIAxes,'on')
                            bar(app.UIAxes,j,arr(j),'g')
                            bar(app.UIAxes,j+1,arr(j+1),'g')
                            text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                            hold(app.UIAxes,'off')
                            app.LabelSorted.Text = num2str(arr);
                        end
                    end
                end
            end
                      
            function MergeSortSelected
                speed = 2;
                vec = str2num(app.LabelUnsorted.Text);
                LengthArray = length(vec);
                LeftArrayStop = round(LengthArray/2);
                LeftArrayStart = 1;
                RightArrayStart =round(LengthArray/2)+1;
                RightArrayStop = LengthArray;
                function arr = InsertionSort(arr,start,stop)
                    
                    for i=start:stop % we use for because  we look at the difference between numbers
                        for j=start:(stop-i)
                            if arr(j+1)<arr(j)
                                value=app.SpeedKnob.Value;
                                if value=="Stop"
                                    value=app.SpeedKnob.Value;
                                    while true
                                        pause(0.1)
                                        if app.SpeedKnob.Value~="Stop"
                                            break
                                        end
                                    end
                                end
                                if strcmp(value,'Clear')
                                    cla(app.UIAxes)
                                    break
                                elseif strcmp(value,'Slow')
                                    speed=2;
                                elseif strcmp(value,'Normal')
                                    speed=1;
                                elseif strcmp(value,'Fast')
                                    speed=0.1;
                                end
                                bar(app.UIAxes,arr,'b')
                                text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                                hold(app.UIAxes,'on')
                                bar(app.UIAxes,j,arr(j),'g')
                                bar(app.UIAxes,j+1,arr(j+1),'g')
                                text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                                hold(app.UIAxes,'off')
                                temporary = arr(j);
                                arr(j) = arr(j+1);
                                arr(j+1) = temporary;
                                pause(speed)
                                bar(app.UIAxes,arr,'b')
                                text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                                hold(app.UIAxes,'on')
                                bar(app.UIAxes,j,arr(j),'g')
                                bar(app.UIAxes,j+1,arr(j+1),'g')
                                text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                                hold(app.UIAxes,'off')
                                pause(speed)
                                app.LabelSorted.Text = num2str(arr);
                            end
                        end
                    end
                end
                vec = InsertionSort(vec,LeftArrayStart,LeftArrayStop);
                vec = InsertionSort(vec,RightArrayStart,RightArrayStop);
                
                LeftArray = vec(1:round(LengthArray/2));
                RightArray = vec(round(LengthArray/2)+1:LengthArray);
                
                LeftArrayLength = length(LeftArray);
                RightArrayLength = length(RightArray);
                
                
                CounterLeft = 1;
                CounterRight = 1;
                CounterArray = 1;
                valid = true;
                
                while valid
                    if CounterLeft <= LeftArrayLength &&  CounterRight <= RightArrayLength
                        if LeftArray(CounterLeft) <= RightArray(CounterRight)
                            vec(CounterArray) = LeftArray(CounterLeft);  LeftArray(CounterLeft)=0;
                            CounterLeft = CounterLeft + 1;
                            CounterArray = CounterArray + 1;
                        elseif LeftArray(CounterLeft) > RightArray(CounterRight)
                            vec(CounterArray) = RightArray(CounterRight);   RightArray(CounterRight)=0;
                            CounterRight = CounterRight + 1;
                            CounterArray = CounterArray + 1;
                        end
                        
                    elseif CounterArray <= LengthArray
                        if CounterLeft > LeftArrayLength
                            vec(CounterArray) = RightArray(CounterRight);   RightArray(CounterRight)=0;
                            CounterRight = CounterRight + 1;
                            CounterArray = CounterArray + 1;
                            
                        elseif CounterRight > RightArrayLength
                            vec(CounterArray) = LeftArray(CounterLeft);  LeftArray(CounterLeft)=0;
                            CounterLeft = CounterLeft + 1;
                            CounterArray = CounterArray + 1;
                        end
                        
                    else
                        valid = false;
                    end
                    bar(app.UIAxes,vec,'b')
                    text(app.UIAxes,1:length(vec),vec,num2str(vec'),'vert','bottom','horiz','center');
                    app.LabelSorted.Text = num2str(arr);
                end
            end
            
            function BubbleSortSelected(app)
                arr=str2num(app.LabelUnsorted.Text);
                bar(app.UIAxes,arr,'b')
                text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                pause(0.5)
                speed=2;
                for i=1:length(arr)
                    %speed
                    value=app.SpeedKnob.Value;
                    if value=="Stop"
                        value=app.SpeedKnob.Value;
                        while true
                            pause(0.1)
                            if app.SpeedKnob.Value~="Stop"
                                break
                            end
                        end
                    end
                    if strcmp(value,'Clear')
                        cla(app.UIAxes)
                        break
                    elseif strcmp(value,'Slow')
                        speed=2;
                    elseif strcmp(value,'Normal')
                        speed=1;
                    elseif strcmp(value,'Fast')
                        speed=0.1;
                    end
                    
                    deneme = arr(1);
                    %deneme=en büyük değer
                    for j=2:(length(arr) - i+1)
                        %speed
                        value=(app.SpeedKnob.Value);
                        if value=="Stop"
                            value=app.SpeedKnob.Value;
                            while true
                                pause(0.1)
                                if app.SpeedKnob.Value~="Stop"
                                    break
                                end
                            end
                        end
                        if strcmp(value,'Clear')
                            cla(app.UIAxes)
                            break
                        elseif strcmp(value,'Slow')
                            speed=2;
                        elseif strcmp(value,'Normal')
                            speed=1;
                        elseif strcmp(value,'Fast')
                            speed=0.1;
                        end
                        
                        if deneme > arr(j)
                            bar(app.UIAxes,arr,'b')
                            text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                            hold(app.UIAxes,'on')
                            bar(app.UIAxes,j-1,arr(j-1),'g')
                            bar(app.UIAxes,j,arr(j),'g')
                            text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                            hold(app.UIAxes,'off')
                            pause(speed)
                            
                            arr(j - 1) = arr(j);
                            arr(j) = deneme;
                            
                            bar(app.UIAxes,arr,'b')
                            text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                            hold(app.UIAxes,'on')
                            bar(app.UIAxes,j-1,arr(j-1),'g')
                            bar(app.UIAxes,j,arr(j),'g')
                            text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                            hold(app.UIAxes,'off')
                            pause(speed)
                            app.LabelSorted.Text = num2str(arr);
                            
                        elseif deneme < arr(j)
                            deneme = arr(j);
                        end
                        
                    end
                end
                if q==0
                    app.LabelSorted.Text=num2str(arr);
                end
            end
            
            function BucketSortSelected
                array = str2num(app.LabelUnsorted.Text);
                k = max(array);
                bucket = zeros(1,k+1);
                B = zeros(1,numel(array));
                speed=1;
                for j = 1:numel(array)
                    bucket(array(j)) = bucket(array(j)) + 1;
                end
                index = 1;
                for i = 1:k+1
                    for j = 1:bucket(i)
                        pause(speed)
                        array(index) = i;
                        index = index + 1;
                        bar(app.UIAxes, array,'b')
                        text(app.UIAxes,1:length(array),array,num2str(array'),'vert','bottom','horiz','center');
                        hold(app.UIAxes,'on')
                        bar(app.UIAxes,index,array(index),'g')
                        text(app.UIAxes,1:length(array),array,num2str(array'),'vert','bottom','horiz','center');
                        hold(app.UIAxes,'off')
                        app.LabelSorted.Text = num2str(array);
                        value=app.SpeedKnob.Value;
                        if value=="Stop"
                            value=app.SpeedKnob.Value;
                            while true
                                pause(0.1)
                                if app.SpeedKnob.Value~="Stop"
                                    break
                                end
                            end
                        end
                        if strcmp(value,'Clear')
                            cla(app.UIAxes)
                            break
                        elseif strcmp(value,'Slow')
                            speed=2;
                        elseif strcmp(value,'Normal')
                            speed=1;
                        elseif strcmp(value,'Fast')
                            speed=0.1;
                        end
                    end
                end
            end
            
            function CountingSortSelected
                speed = 2;
                arr = str2num(app.LabelSorted.Text);
                maxRange = app.MaximumValueSpinner.Value;
                n = numel(arr);
                count = zeros(maxRange,1);
                for j = 1:n
                    count(arr(j)) = count(arr(j)) + 1;
                end
                % Convert to cumulative values
                for i = 2:maxRange
                    count(i) = count(i) + count(i - 1);
                end
                % Sort the array
                sortedArray = zeros(n,1);
                bar(app.UIAxes,arr,'b')
                text(app.UIAxes,1:length(arr),arr,num2str(arr'),'vert','bottom','horiz','center');
                hold(app.UIAxes,'on')
                for j = n:-1:1
                    sortedArray(count(arr(j))) = arr(j);
                    count(arr(j)) = count(arr(j)) - 1;
                    value=app.SpeedKnob.Value;
                    if value=="Stop"
                        value=app.SpeedKnob.Value;
                        while true
                            pause(0.1)
                            if app.SpeedKnob.Value~="Stop"
                                break
                            end
                        end
                    end
                    if strcmp(value,'Clear')
                        cla(app.UIAxes)
                        break
                    elseif strcmp(value,'Slow')
                        speed=2;
                    elseif strcmp(value,'Normal')
                        speed=1;
                    elseif strcmp(value,'Fast')
                        speed=0.1;
                    end
                    pause(speed)
                    bar(app.UIAxes,sortedArray,'b')
                    text(app.UIAxes,1:length(sortedArray),sortedArray,num2str(sortedArray),'vert','bottom','horiz','center');
                    hold(app.UIAxes,'on')
                    text(app.UIAxes,1:length(sortedArray),sortedArray,num2str(sortedArray),'vert','bottom','horiz','center');
                    hold(app.UIAxes,'off')
                    app.LabelSorted.Text = num2str(sortedArray');
                end
                
            end
            
            function HeapSortSelected
                A = str2num(app.LabelUnsorted.Text);
                n = length(A);
                for i = n:-1:1
                    A = heapify(A,n,i);
                    value=app.SpeedKnob.Value;
                    if value=="Stop"
                        value=app.SpeedKnob.Value;
                        while true
                            pause(0.1)
                            if app.SpeedKnob.Value~="Stop"
                                break
                            end
                        end
                    end
                    if strcmp(value,'Clear')
                        cla(app.UIAxes)
                        break
                    elseif strcmp(value,'Slow')
                        speed=2;
                    elseif strcmp(value,'Normal')
                        speed=1;
                    elseif strcmp(value,'Fast')
                        speed=0.1;
                    end
                    pause(speed)
                    bar(app.UIAxes,A,'b')
                    text(app.UIAxes,1:length(A),A,num2str(A'),'vert','bottom','horiz','center');
                    hold(app.UIAxes,'on')
                    text(app.UIAxes,1:length(A),A,num2str(A'),'vert','bottom','horiz','center');
                    hold(app.UIAxes,'off')
                    app.LabelSorted.Text = num2str(A);
                end
                
                n2 = length(A);
                for i = n2:-1:2
                    A([i 1]) = A([1 i]);
                    n = n-1;
                    A = heapify(A, i, 1);
                    value=app.SpeedKnob.Value;
                    if value=="Stop"
                        value=app.SpeedKnob.Value;
                        while true
                            pause(0.1)
                            if app.SpeedKnob.Value~="Stop"
                                break
                            end
                        end
                    end
                    if strcmp(value,'Clear')
                        cla(app.UIAxes)
                        break
                    elseif strcmp(value,'Slow')
                        speed=2;
                    elseif strcmp(value,'Normal')
                        speed=1;
                    elseif strcmp(value,'Fast')
                        speed=0.1;
                    end
                    pause(speed)
                    bar(app.UIAxes,A,'b')
                    text(app.UIAxes,1:length(A),A,num2str(A'),'vert','bottom','horiz','center');
                    hold(app.UIAxes,'on')
                    text(app.UIAxes,1:length(A),A,num2str(A'),'vert','bottom','horiz','center');
                    hold(app.UIAxes,'off')
                    app.LabelSorted.Text = num2str(A);
                end
                
                
                function sort1 = heapify(A,n,i)
                    largest = i;
                    l = 2*i;
                    r = 2*i+1;
                    
                    if l<n && A(i)<A(l)
                        largest = l;
                    end
                    
                    if r<n && A(largest)<A(r)
                        largest = r;
                    end
                    
                    if largest ~= i
                        A([largest i]) = A([i largest]);
                        A = heapify(A, n, largest);
                    end
                    sort1 = A;
                end
            end
            
            function RadixSortSelected
                unsortedArray = str2num(app.LabelUnsorted.Text);
                divide=1;
                start=4;
                total=0;
                temporaryArray=[];
                motherArray=[];
                speed = 2;
                while start>0
                    c=mod(floor(max(unsortedArray)/divide),10);
                    if c==0
                        start=start-1;
                    else
                        start=4;
                    end
                    divide=divide*10;
                    total=total+1;
                end
                
                total=total-4;
                
                for element=1:numel(unsortedArray)
                    divide=10^(total-1);
                    for number=1:total
                        temporaryArray=[temporaryArray,mod(floor(unsortedArray(element)/divide),10)];
                        divide=divide/10;
                    end
                    
                    motherArray=[motherArray;temporaryArray];
                    temporaryArray=[];
                    
                end
                
                temporaryArray=[];
                
                for i=0:total-1
                    for look=0:9
                        for search=1:size(motherArray,1)
                            if look==motherArray(search,size(motherArray,2)-i)
                                temporaryArray=[temporaryArray;motherArray(search,:)];
                            end
                        end


                    end
                    motherArray=temporaryArray;
                    temporaryArray=[];
                    
                    
                end
                temporaryArray=zeros(1,length(unsortedArray));
                for number=1:size(motherArray,1)
                    sum=0;
                    multiplier=10^(total-1);
                    for final=1:size(motherArray,2)
                        sum=sum+(motherArray(number,final)*multiplier);
                        multiplier=multiplier/10;
                    end
                    %temporaryArray=[temporaryArray,sum];
                    temporaryArray(number)=sum;
                    %speed
                    value=app.SpeedKnob.Value;
                    if value=="Stop"
                        value=app.SpeedKnob.Value;
                        while true
                            pause(0.1)
                            if app.SpeedKnob.Value~="Stop"
                                break
                            end
                        end
                    end
                    if strcmp(value,'Clear')
                        cla(app.UIAxes)
                        break
                    elseif strcmp(value,'Slow')
                        speed=2;
                    elseif strcmp(value,'Normal')
                        speed=1;
                    elseif strcmp(value,'Fast')
                        speed=0.1;
                    end
                    pause(speed)
                    bar(app.UIAxes, temporaryArray,'b')
                    text(app.UIAxes,1:length(temporaryArray),temporaryArray,num2str(temporaryArray'),'vert','bottom','horiz','center');
                    app.LabelSorted.Text = num2str(temporaryArray);
                end
            end
            
            function QuickSortSelected
                
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SortingVisualized = uifigure('Visible', 'off');
            app.SortingVisualized.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.SortingVisualized);
            app.VisualizeSortTab = uitab(app.TabGroup);
            app.VisualizeSortTab.Title = 'Visualize & Sort';
            app.MinimumValueSpinnerLabel = uilabel(app.VisualizeSortTab);
            app.MinimumValueSpinnerLabel.Text = 'Minimum Value';
            app.MinimumValueSpinner = uispinner(app.VisualizeSortTab);
            app.MinimumValueSpinner.Tooltip = {'Choose lower limit of numbers'};
            app.MaximumValueSpinnerLabel = uilabel(app.VisualizeSortTab);
            app.MaximumValueSpinnerLabel.Text = 'Maximum Value';
            app.MaximumValueSpinner = uispinner(app.VisualizeSortTab);
            app.MaximumValueSpinner.Tooltip = {'Choose upper limit of numbers'};
            app.MaximumValueSpinner.Value = 100;
            app.ArrayLengthSpinnerLabel = uilabel(app.VisualizeSortTab);
            app.ArrayLengthSpinnerLabel.Text = 'Array Length';
            app.ArrayLengthSpinner = uispinner(app.VisualizeSortTab);
            app.ArrayLengthSpinner.Tooltip = {'Choose Array Length'};
            app.ArrayLengthSpinner.Value = 15;
            app.RandomArrayLabel = uilabel(app.VisualizeSortTab);
            app.RandomArrayLabel.Text = 'Random Array';
            app.SortedArrayLabel = uilabel(app.VisualizeSortTab);
            app.SortedArrayLabel.Text = 'Sorted Array';
            app.LabelUnsorted = uilabel(app.VisualizeSortTab);
            app.LabelUnsorted.Text = '';
            app.LabelSorted = uilabel(app.VisualizeSortTab);
            app.LabelSorted.Text = '';
            app.SortingAlgorithmsButtonGroup = uibuttongroup(app.VisualizeSortTab);
            app.SortingAlgorithmsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SortingAlgorithmsButtonGroupSelectionChanged, true);
            app.SortingAlgorithmsButtonGroup.Tooltip = {'Seleckt a Sorting Algorithm to see how it works.'};
            app.SortingAlgorithmsButtonGroup.Title = 'Sorting Algorithms';
            app.InsertionButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.InsertionButton.Tooltip = {'Click for Insertion Sort'};
            app.InsertionButton.Text = 'Insertion';
            app.InsertionButton.Value = true;
            app.MergeButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.MergeButton.Tooltip = {'Click for Merge Sort'};
            app.MergeButton.Text = 'Merge';
            app.BubbleButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.BubbleButton.Tooltip = {'Click for Bubble Sort'};
            app.BubbleButton.Text = 'Bubble';
            app.BucketButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.BucketButton.Tooltip = {'Click for Bucket '; 'Sort'};
            app.BucketButton.Text = 'Bucket';
            app.RadixButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.RadixButton.Tooltip = {'Click for Radix Sort'};
            app.RadixButton.Text = 'Radix';
            app.QuickButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.QuickButton.Tooltip = {'Click for Quick Sort'};
            app.QuickButton.Text = 'Quick';
            app.HeapButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.HeapButton.Tooltip = {'Click for Heap Sort'};
            app.HeapButton.Text = 'Heap';
            app.CountingButton = uiradiobutton(app.SortingAlgorithmsButtonGroup);
            app.CountingButton.Tooltip = {'Click for Counting Sort'};
            app.CountingButton.Text = 'Counting';
            app.CreateArrayButton = uibutton(app.VisualizeSortTab, 'push');
            app.CreateArrayButton.ButtonPushedFcn = createCallbackFcn(app, @CreateArrayButtonPushed, true);
            app.CreateArrayButton.Tooltip = {'Press to create random array.'};
            app.CreateArrayButton.Text = 'Create Array';
            app.SpeedKnobLabel = uilabel(app.VisualizeSortTab);
            app.SpeedKnobLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.VisualizeSortTab, 'discrete');
            app.SpeedKnob.Items = {'Stop', 'Slow', 'Normal', 'Fast', 'Clear'};
            app.SpeedKnob.Tooltip = {'Choose s'; 'peed of animation'};
            app.SpeedKnob.Value = 'Normal';
            app.UIAxes = uiaxes(app.VisualizeSortTab);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, 'Index')
            ylabel(app.UIAxes, '')
            app.UIAxes.YTick = [];
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.AuthorMuhammetFurkanMUTULabel = uilabel(app.AboutTab);
            app.AuthorMuhammetFurkanMUTULabel.Text = {'Author: '; 'Muhammet Furkan MUŞTU'};
            app.Image = uiimage(app.AboutTab);
            app.Image.ImageSource = 'ikc.png';
            app.EMailmfurkanmustugmailomLabel = uilabel(app.AboutTab);
            app.EMailmfurkanmustugmailomLabel.Text = {'E-Mail:'; 'mfurkanmustu@gmail.om'};
            app.Label = uilabel(app.AboutTab);
            app.Label.Text = {'Source Code:'; 'https://github.com/cinereus/Sorting-Arlgorithms-Visualized'};
            % Show the figure after all components are created
            app.SortingVisualized.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SortingVisualizedProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SortingVisualized)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SortingVisualized)
        end
    end
end